classdef LunarGame < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure       matlab.ui.Figure
        TextArea       matlab.ui.control.TextArea
        TextAreaLabel  matlab.ui.control.Label
        Image          matlab.ui.control.Image
        GamePanel      matlab.ui.container.Panel
        StartButton    matlab.ui.control.Button
    end

    properties (Access = private)
        rocket = Rocket(randi([150,850]), randi([350,450]), 0, -20); % 使用构造函数传递坐标

        plane_pos;
        McDonal_pos;
        Height;

        rocketImage; % 火箭图像对象
        McDonald; % 麦当劳对象

        upKeyPressed = false;
        leftKeyPressed = false;
        rightKeyPressed = false;
        
        lose = false;
        victory = false;

        TimeLable;
        FuelLable;

    end

    methods (Access = private)

        %绘制火箭
       function createRocketImage(app)
            % 获取火箭图像文件的路径
            rocketImagePath = fullfile('D:', 'Matlab', 'code', 'OIP.png'); 
            
            % 检查图像文件是否存在
            if isfile(rocketImagePath)
                % 如果火箭图像组件不存在或无效，则创建它
                if isempty(app.rocketImage) || ~isvalid(app.rocketImage)
                    app.rocketImage = uiimage(app.GamePanel);
                end

                % 更新火箭图像组件的属性
                app.rocketImage.Position = [app.rocket.x, app.rocket.y, app.rocket.width, app.rocket.length]; % 设置图像位置和尺寸
                app.rocketImage.ImageSource = rocketImagePath;
        
            else
                % 如果图像文件不存在，显示一个默认的占位符图像
                if isempty(app.rocketImage) || ~isvalid(app.rocketImage)
                    app.rocketImage = uiimage(app.GamePanel);
                end
                app.rocketImage.Position = [50, 50, 100, 100]; % 设置图像位置和尺寸
                % 使用默认占位符图像
                app.rocketImage.ImageSource = 'https://via.placeholder.com/100';
                warning('图像文件未找到，使用默认占位符图像。');

            end
        end
        %更新火箭位置
       function updateRocketImageSource(app)
            newX = app.rocket.x;
            newY = app.rocket.y;
            app.rocketImage.Position = [newX,newY,app.rocket.width,app.rocket.length];
        end

        % function deleteRocketImage(app)
        %     % 删除火箭图像
        %      if ~isempty(app.rocketImage) && isvalid(app.rocketImage)
        %         delete(app.rocketImage);
        %         app.rocketImage = [];
        %      end
        % end
        
        % 绘制山脉
       function plotLine(app)
            % Define x and y data for the line

            axes = uiaxes(app.GamePanel);
            axes.Position = [0 ,0,app.GamePanel.Position(3)+80,200];
            axes.Color = [0,0,0];
            axes.XColor = 'none';
            axes.YColor = 'none';

            % 确定散点
            x = 0:40:app.GamePanel.Position(3);
            x(numel(x)+1) =app.GamePanel.Position(3);
            y = randi([0,150],1,numel(x))+50;

            % 插入平台
            for i = 1:randi([1,3])

                x_0 = randi([3,numel(x)-2]);
                pos(i) = x_0;
                y(x_0) = randi([100,200]);
                y(x_0+1) = y(x_0);
                y(x_0+2) = y(x_0);
                
            end
            %disp(pos);
            new_x = 0:1:app.GamePanel.Position(3)-1;
            new_y = interp1(x,y,new_x,'linear','extrap');
            app.Height = new_y;
            app.plane_pos = pos.*40;
            disp(app.plane_pos)
            app.McDonal_pos = app.plane_pos(randi([1,numel(app.plane_pos)]));
            disp(app.McDonal_pos);
            disp(new_y);
            %画山脉
            plot(axes, x, y, 'color','w', 'MarkerSize', 8);
            ylim(axes,[0,200]);
            fill(axes, [new_x, app.GamePanel.Position(3), 0], [new_y, 0, 0], 'w');

        end
        % 创建时间燃料标签
       function createlabel (app)
            app.TimeLable = uilabel(app.GamePanel);
            app.TimeLable.FontSize = 14;
            app.TimeLable.HorizontalAlignment = 'center'; % 水平居中
            app.TimeLable.Position = [50, 50, 200, 22]; % 适当调整位置和大小
            app.TimeLable.Text = "Time: 0.00 s"; % 初始化文本
   
            app.FuelLable = uilabel(app.GamePanel);
            app.FuelLable.FontSize = 14;
            app.FuelLable.FontColor = 'w';
            app.FuelLable.Position = [app.GamePanel.Position(3)-100, app.GamePanel.Position(4)-50, 200, 22]; % 适当调整位置和大小
            app.FuelLable.Text = "Fuel : "; % 初始化文本
        end
        % 更新时间燃料标签
       function updateLable (app,startTime)
            Time = toc(startTime);
            timeStr = sprintf('Time: %.2f s',Time);
            app.TimeLable.Text = timeStr;
            fuelStr = sprintf('Fuel: %.2f ',app.rocket.fuel);
            app.FuelLable.Text = fuelStr;
        end
        % 画麦当劳
       function createMcDonald(app)
        % 设置麦当劳图像的路径
            McDonaldImagePath = fullfile('D:', 'Matlab', 'code', 'McDonald.png'); 
        
            % 检查图像文件是否存在
            if isfile(McDonaldImagePath)
                % 如果 McDonald 属性不存在或无效，创建它
                if isempty(app.McDonald) || ~isvalid(app.McDonald)
                    app.McDonald = uiimage(app.GamePanel);
                end
                
                % 设置麦当劳图像的位置和尺寸
                % 根据需要调整位置和尺寸 (这里假设图标大小为 30x30)
                app.McDonald.Position = [app.McDonal_pos, app.Height(app.McDonal_pos), ...
                                            30, 30]; 
                
                % 设置图像源
                app.McDonald.ImageSource = McDonaldImagePath;
            
            else
                % 如果图像文件不存在，显示一个默认的占位符图像
                if isempty(app.McDonald) || ~isvalid(app.McDonald)
                    app.McDonald = uiimage(app.GamePanel);
                end
                app.McDonald.Position = [app.McDonal_pos, app.Height(app.McDonal_pos), 30, 30]; % 设置图像位置和尺寸
                
                % 使用默认占位符图像
                app.McDonald.ImageSource = 'https://via.placeholder.com/50';
                
                % 发出警告，提示图像文件未找到
                warning('麦当劳图像文件未找到，使用默认占位符图像。');
            end
       end
        % 检查胜利条件
       function checkState(app)
            % 判断结束条件
            
            % 判断胜利条件
            for i = 1:1:numel(app.plane_pos)
                % 判断是不是在平台上
                if app.rocket.x <app.plane_pos(i)+45 && app.rocket.x>app.plane_pos(i)-45 %延长一定范围，以防有偏差
                    if(app.rocket.y <= app.Height(floor(app.rocket.x)))
                        app.victory = true;
                        %disp("Hello from win condition");
                        %disp(app.plane_pos(i));
                        return;
                    end
                end
            end
            %判断失败条件
            for i = 1:1:numel(app.plane_pos)
                   if(app.rocket.y<=app.Height(floor(app.rocket.x)))
                        app.lose = true;
                        %disp("Hello from lose condition");
                        %disp(app.plane_pos(i));
                        return;
                   end
            end

       end
        % 结尾
       function showEnding(app)
        if app.victory
            title = 'Victory!';
            msg = 'Congratulations! You have successfully landed!';
            icon = 'success'; % 使用成功图标
        elseif app.lose
            title = 'Game Over';
            msg = 'Sorry, you crashed. Better luck next time!';
            icon = 'error'; % 使用错误图标
        else
            error('Invalid game state for showing ending.'); % 确保只有在胜利或失败时被调用
        end
        
        % 使用 uialert 显示消息框
        uialert(app.UIFigure, msg, title, 'Icon', icon);
    end
        % 检查按键
       function checkKey(app)
            if app.upKeyPressed
                app.rocket = app.rocket.accelerate;
                app.upKeyPressed = false; % 重置按键状态
            elseif app.leftKeyPressed
                app.rocket = app.rocket.turnLeft;
                app.leftKeyPressed = false;
            elseif app.rightKeyPressed
                app.rocket = app.rocket.turnRight;
                app.rightKeyPressed = false;
            end
        end

       function startGame(app)
            startTime = tic;
            plotLine(app);
            createRocketImage(app);
            createlabel(app);
            createMcDonald(app);

            while (~app.lose&&~app.victory)
                pause(0.1);
                checkKey(app);
                % 判断结束条件
                checkState(app);
                app.rocket = app.rocket.UpdateRocketState(0.1);
                updateLable(app,startTime);
                updateRocketImageSource(app);
                drawnow    
            end

            showEnding(app);
        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            startGame(app);
        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            key = event.Key;
            switch (key)
                case 'w'
                    app.upKeyPressed = true;
                case 'a'
                    app.leftKeyPressed = true;
                case 'd'
                    app.rightKeyPressed = true;
                otherwise
            end
            drawnow;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.GamePanel = uipanel(app.UIFigure);
            app.GamePanel.Title = 'Game';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'th.jpg');
            app.TextAreaLabel = uilabel(app.UIFigure);
            app.TextAreaLabel.Text = 'Text Area';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Value = {'这是一个模拟月球着陆登陆器'; '你将扮演一个伟大的宇航员，选择合适的位置登上月球，实现人类的一大步。'; '在这个过程中，你将操作火箭实现向左偏移，向右偏移，以及减速甚至反向加速，注意你的每次偏移的横向速度与你当时的速度有关。每次操作都会消耗一定燃料，每次偏移消耗五点燃料，向上偏移消耗十点燃料。一共有200燃料。当燃料用尽后，你无法进行任何操作。'; '（我们贴心地为饥肠辘辘的宇航员建了一个麦当劳，请尽情享受）'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = LunarGame

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end